classdef RawatProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        VerticalShiftsEditField       matlab.ui.control.NumericEditField
        VerticalShiftsEditFieldLabel  matlab.ui.control.Label
        PhaseShiftsEditField          matlab.ui.control.NumericEditField
        PhaseShiftsEditFieldLabel     matlab.ui.control.Label
        PeriodEditField               matlab.ui.control.NumericEditField
        PeriodEditFieldLabel          matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        WaveDropDown                  matlab.ui.control.DropDown
        WaveDropDownLabel             matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        PlotButton                    matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        Switch                        matlab.ui.control.Switch
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
                app.Lamp.Color = 'r';
                cla(app.UIAxes);
                app.AmplitudeEditField.Value = 1;
                app.PeriodEditField.Value = 1;
                app.PhaseShiftsEditField.Value = 0;
                app.VerticalShiftsEditField.Value = 0;
            else
                app.Lamp.Color = 'g';
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            a = app.AmplitudeEditField.Value ;
            T = app.PeriodEditField.Value;
            b = (2*pi)/T;
            c = app.PhaseShiftsEditField.Value;
            d = app.VerticalShiftsEditField.Value;
            value = app.Switch.Value;
            Wtype = app.WaveDropDown.Value;
            if strcmp(value,'On') && strcmp(Wtype,'Sine')
                cla(app.UIAxes);
                x = 0:0.01: (360/180)*pi;
                y = a*sin((b*x)+c)+d;
                plot(app.UIAxes,y);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {212, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WaveDropDownLabel = uilabel(app.LeftPanel);
            app.WaveDropDownLabel.Text = 'Wave';
            app.WaveDropDown = uidropdown(app.LeftPanel);
            app.WaveDropDown.Items = {'Sine', 'S', 'T'};
            app.WaveDropDown.Value = 'Sine';
            app.AmplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudeEditField.Value = 1;
            app.PeriodEditFieldLabel = uilabel(app.LeftPanel);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PeriodEditField.Value = 1;
            app.PhaseShiftsEditFieldLabel = uilabel(app.LeftPanel);
            app.PhaseShiftsEditFieldLabel.Text = 'Phase Shifts';
            app.PhaseShiftsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VerticalShiftsEditFieldLabel = uilabel(app.LeftPanel);
            app.VerticalShiftsEditFieldLabel.Text = 'Vertical Shifts';
            app.VerticalShiftsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Wave')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.RightPanel);
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RawatProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end